classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        EntradaTab                     matlab.ui.container.Tab
        MuestrasobtenidasEditField     matlab.ui.control.NumericEditField
        MuestrasobtenidasEditFieldLabel  matlab.ui.control.Label
        TiemposEditField               matlab.ui.control.NumericEditField
        TiemposEditFieldLabel          matlab.ui.control.Label
        MuestrasEditField              matlab.ui.control.NumericEditField
        MuestrasEditFieldLabel         matlab.ui.control.Label
        FsEditField                    matlab.ui.control.NumericEditField
        FsEditFieldLabel               matlab.ui.control.Label
        CargarmatButton                matlab.ui.control.Button
        CapturarButton                 matlab.ui.control.Button
        OfflineLabel                   matlab.ui.control.Label
        OnlineLabel                    matlab.ui.control.Label
        BaudrateDropDown               matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        SourceButtonGroup              matlab.ui.container.ButtonGroup
        OfflineButton                  matlab.ui.control.RadioButton
        OnlineButton                   matlab.ui.control.RadioButton
        DetenerButton                  matlab.ui.control.Button
        LimpiarButton                  matlab.ui.control.Button
        ConectarButton                 matlab.ui.control.Button
        SeleccionarelCOMDropDown       matlab.ui.control.DropDown
        SeleccionarelCOMDropDownLabel  matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        EditField_6                    matlab.ui.control.EditField
        EditField_3                    matlab.ui.control.EditField
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        DiseoOfflineTab                matlab.ui.container.Tab
        CategoriaDropDown              matlab.ui.control.DropDown
        CategoriaDropDownLabel         matlab.ui.control.Label
        EditField_9                    matlab.ui.control.EditField
        AlternarejeVerticalMagnitudButtonGroup  matlab.ui.container.ButtonGroup
        LinealButton                   matlab.ui.control.RadioButton
        dBButton                       matlab.ui.control.RadioButton
        AlternarejeHorizontalMagnitudButtonGroup  matlab.ui.container.ButtonGroup
        RadMuestraButton_2             matlab.ui.control.RadioButton
        HzButton_2                     matlab.ui.control.RadioButton
        AlternarejeHorizontalFaseButtonGroup  matlab.ui.container.ButtonGroup
        RadMuestraButton               matlab.ui.control.RadioButton
        HzButton                       matlab.ui.control.RadioButton
        AplicarButton                  matlab.ui.control.Button
        Fcorte2EditField               matlab.ui.control.NumericEditField
        Fcorte2EditFieldLabel          matlab.ui.control.Label
        Fcorte1EditField               matlab.ui.control.NumericEditField
        Fcorte1EditFieldLabel          matlab.ui.control.Label
        OrdenEditField                 matlab.ui.control.NumericEditField
        OrdenEditFieldLabel            matlab.ui.control.Label
        RetrasoEditField               matlab.ui.control.NumericEditField
        RetrasoEditFieldLabel          matlab.ui.control.Label
        GananciaEditField              matlab.ui.control.NumericEditField
        GananciaEditFieldLabel         matlab.ui.control.Label
        FcEditField                    matlab.ui.control.NumericEditField
        FcEditFieldLabel               matlab.ui.control.Label
        EfectosDropDown                matlab.ui.control.DropDown
        EfectosDropDownLabel           matlab.ui.control.Label
        EditField_5                    matlab.ui.control.EditField
        FiltroDropDown                 matlab.ui.control.DropDown
        FiltroDropDownLabel            matlab.ui.control.Label
        TipodeFiltroButtonGroup        matlab.ui.container.ButtonGroup
        FIRButton                      matlab.ui.control.RadioButton
        IIRButton                      matlab.ui.control.RadioButton
        EditField_4                    matlab.ui.control.EditField
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        DiseoOnlineTab                 matlab.ui.container.Tab
        FcEditField_2                  matlab.ui.control.NumericEditField
        FcEditField_2Label             matlab.ui.control.Label
        GananciaEditField_2            matlab.ui.control.NumericEditField
        GananciaEditField_2Label       matlab.ui.control.Label
        RetrasoEditField_2             matlab.ui.control.NumericEditField
        RetrasoEditField_2Label        matlab.ui.control.Label
        OrdenEditField_2               matlab.ui.control.NumericEditField
        OrdenEditField_2Label          matlab.ui.control.Label
        Fcorte1EditField_2             matlab.ui.control.NumericEditField
        Fcorte1EditField_2Label        matlab.ui.control.Label
        Fcorte2EditField_2             matlab.ui.control.NumericEditField
        Fcorte2EditField_2Label        matlab.ui.control.Label
        AplicarButton_2                matlab.ui.control.Button
        AlternarejeVerticalMagnitudButtonGroup_2  matlab.ui.container.ButtonGroup
        LinealButton_2                 matlab.ui.control.RadioButton
        dBButton_2                     matlab.ui.control.RadioButton
        AlternarejeHorizontalMagnitudButtonGroup_2  matlab.ui.container.ButtonGroup
        RadMuestraButton_4             matlab.ui.control.RadioButton
        HzButton_4                     matlab.ui.control.RadioButton
        AlternarejeHorizontalFaseButtonGroup_2  matlab.ui.container.ButtonGroup
        RadMuestraButton_3             matlab.ui.control.RadioButton
        HzButton_3                     matlab.ui.control.RadioButton
        EfectosDropDown_2              matlab.ui.control.DropDown
        EfectosDropDown_2Label         matlab.ui.control.Label
        EditField_8                    matlab.ui.control.EditField
        FiltroDropDown_2               matlab.ui.control.DropDown
        FiltroDropDown_2Label          matlab.ui.control.Label
        EditField_7                    matlab.ui.control.EditField
        UIAxes2_3                      matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        SalidaTab                      matlab.ui.container.Tab
        ExportarencsvButton            matlab.ui.control.Button
        UIAxes2_5                      matlab.ui.control.UIAxes
        UIAxes2_4                      matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signal_original % Description
        Fs % Description
        stm32 %Variable stm32 para generar la conexion
        a_coeff %Coeficiente de a 
        b_coeff %Coeficiente de b
        FIR
        signal_procesada
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarmatButton
        function CargarmatButtonPushed(app, event)
            [file, path] = uigetfile('*mat', 'Seleccionar el archivo por cargar');


            if isequal(file, 0)
                return;
            end
         data = load(fullfile(path, file));

         if isfield(data, 'x') && isfield(data, 'Fs')
             signal = data.x;
             fs = data.Fs;
             app.FsEditField.Value = fs;
             app.MuestrasEditField.Value = length(signal);
             app.signal_original = signal;
             app.Fs = fs;
             n = length(signal);
             L = n;
             t = (0:n-1)/fs;

             plot(app.UIAxes, t, signal);
             title(app.UIAxes, 'Señal original cargada');
             xlabel(app.UIAxes, 'Tiempo (s)');
             ylabel(app.UIAxes, 'Amplitud');

             signal_sin_dc = signal - mean(signal);
             Y = fft(signal_sin_dc);
             espectro = abs(Y/L);
             espectro_uni = espectro(1 : floor(L/2) + 1);
             espectro_uni(2:end-1) = 2 * espectro_uni(2:end-1);

             frecuencia = fs*(0 : (L/2)) / L;
             plot(app.UIAxes2, frecuencia, espectro_uni);
             title(app.UIAxes2, 'Señal en dominio de la frecuencia');
             xlabel(app.UIAxes2, 'Frecuencia (Hz)');
             ylabel(app.UIAxes2, 'Amplitud');

             histogram(app.UIAxes3, signal, 'NumBins',50, 'FaceColor', '#0072BD', 'EdgeColor', 'w');
             title(app.UIAxes3, 'Distribucion de Amplitud (Histograma)');
             xlabel(app.UIAxes3, 'Amplitud');
             ylabel(app.UIAxes3, 'Conteo (Ocurrencias)');


         else
             uialert(app.UIFigure, 'El archivo no contiene las variables x y Fs', 'Error de formato :(');
         end


        end

        % Value changed function: FsEditField
        function FsEditFieldValueChanged(app, event)
            
        end

        % Selection changed function: SourceButtonGroup
        function SourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.SourceButtonGroup.SelectedObject;
            
            if selectedButton == app.OfflineButton
                app.SeleccionarelCOMDropDown.Enable = 'off';
                app.ConectarButton.Enable = 'off';
                app.CapturarButton.Enable = 'off';
                app.DetenerButton.Enable = 'off';
                app.TiemposEditField.Enable = 'off';
                
                % Encender controles Offline
                app.CargarmatButton.Enable = 'on';
                app.FsEditField.Enable = 'on';
                app.MuestrasEditField.Enable = 'on';
                
            elseif selectedButton == app.OnlineButton
                app.SeleccionarelCOMDropDown.Enable = 'on';
                app.ConectarButton.Enable = 'on';
                app.CapturarButton.Enable = 'on';
                app.DetenerButton.Enable = 'on';
                app.TiemposEditField.Enable = 'on';
                
                % Apagar controles Offline
                app.CargarmatButton.Enable = 'off';
                app.FsEditField.Enable = 'off';
                app.MuestrasEditField.Enable = 'off';
            end
            
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.signal_original = [];
            app.Fs = [];

            app.FsEditField.Value = 0;
            app.MuestrasEditField.Value = 0;
            app.TiemposEditField.Value = 0;


            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);

        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            puerto_seleccionado = app.COMDropDown.Value;
            baudrate = str2double(app.BaudrateDropDown.Value);
            
            try
                app.stm32 = serialport(puerto_seleccionado, baudrate); %Conectar con stm32
                app.ConectarButton.Text = 'Conectado';
                app.ConectarButton.BackgroundColor = [0.47, 0.87, 0.47]; %Boton verde
                app.COMDropDown.Enable = 'off'; %Bloquear el COM para que no lo muevan

            catch ME
                uialert(app.UIFigure, ['No se pudo conectar a ' puerto_seleccionado '. Error']); %Mensaje de error
                app.ConectarButton.Text = 'Conectar';
                app.ConectarButton.BackgroundColor = [0.9, 0.9, 0.9]; %Si no se puede conectar el boton vuelve a la normalidad
            end
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            if ~isempty(app.stm32)
                clear app.stm32;
                app.stm32 = [];
            end
            app.ConectarButton.Text = 'Conectar';
            app.ConectarButton.BackgroundColor = [0.9, 0.9, 0.9];
            app.COMDropDown = 'on';
            
        end

        % Selection changed function: TipodeFiltroButtonGroup
        function TipodefiltroButtonGroup_2SelectionChanged(app, event)
        filtro_tipo = app.TipodeFiltroButtonGroup.SelectedObject;
                    %Tipo de filtro (FIR o IIR)
            if filtro_tipo == app.FIRButton
                app.FiltroDropDown.Enable = 'off';
                app.FIR = true;
            else
                app.FiltroDropDown.Enable = 'on';
                app.FIR = false;
            end
            
        end

        % Button pushed function: AplicarButton
        function AplicarButton_2Pushed(app, event)
            if isempty(app.Fs)
                uialert(app.UIFigure, 'Primero debe cargar un archivo en la pestaña de entrada', 'Falta Fs');
                return;
            end

            orden = app.OrdenEditField.Value;
            fc = app.FcEditField.Value;

            %Defensa para el caso en el que el orden se deja en cero 
            if orden <= 0
                uialert(app.UIFigure, 'El orden del filtro debe ser un numero entero mayor a 0', 'Valor invalido');
                return;
            end
            f_nyquist = app.Fs / 2;

            if fc <= 0 || fc >= f_nyquist
                uialert(app.UIFigure, ['La frecuencia de corte debe ser mayor a 0 y menor a: ', num2str(f_nyquist), 'Hz'], 'Valor invalido');
                return;
            end

            
            fc1 = app.Fcorte1EditField.Value;
            fc2 = app.Fcorte2EditField.Value;
            dos_frecuencias = false;

            %Aca va a ir el if para ver que el filtro a aplicar y el
            %dropdown del tipo
            filtro_categoria = app.CategoriaDropDown.Value;
            filtro_selector = app.FiltroDropDown.Value;

            switch filtro_categoria
                case 'Low-pass'
                    tipo_matlab = 'low';
                case 'Band-pass'
                    tipo_matlab = 'bandpass';
                    dos_frecuencias = true;
                case 'High-pass'
                    tipo_matlab = 'high';
                case 'Band-filter'
                    tipo_matlab = 'stop';
                    dos_frecuencias = true;
                otherwise
                    tipo_matlab = 'low';
            end

            %Logica de dos frecuencias (si son necesarias)

            if dos_frecuencias == true
                if fc1 <= 0 || fc2 <= 0 || fc1 >= f_nyquist || fc2 >= f_nyquist
                    uialert(app.UIFigure, ['Las frecuencias deben estar entre 0 y ', num2str(f_nyquist)], 'Hz', 'Valor invalido');
                    return;
                end

                if fc1 >= fc2
                    uialert(app.UIFigure, 'Para este filtro, F de corte 1 debe ser menor que F de corte 2. Invierta los valores', 'Logica inversa');
                    return;
                end
                wn = [fc1/f_nyquist, fc2/f_nyquist];

            else
                if (fc1 <= 0 || fc1 >= f_nyquist) && fc == 0
                    uialert(app.UIFigure, ['La frecuencia de corte debe ser mayor a 0 y menor a: ', num2str(f_nyquist), 'Hz'], 'Valor invalido');
                    return;
                end

                wn = fc / f_nyquist;
            end
            
            %Rizado permitido por defecto:
            Rp = 1;
            Rs = 40;

            if app.FIR == true
                app.b_coeff = fir1(orden, wn, tipo_matlab);
                app.a_coeff = 1;
            else
                switch filtro_selector
                    case 'Butterworth'
                        [app.b_coeff, app.a_coeff] = butter(orden, wn, tipo_matlab);
                    case 'Cheby1'
                        [app.b_coeff, app.a_coeff] = cheby1(orden, Rp, wn, tipo_matlab);
                    case 'Cheby2'
                        [app.b_coeff, app.a_coeff] = cheby2(orden, Rs, wn, tipo_matlab);
                    case 'Bessel'
                        [app.b_coeff, app.a_coeff] = besself(orden, wn, tipo_matlab);
                    case 'Eliptico'
                        [app.b_coeff, app.a_coeff] = ellip(orden, Rp, Rs, wn, tipo_matlab);
                end
            end
            
            ganancia_val = app.GananciaEditField.Value;

            if ganancia_val == 0
                uialert(app.UIFigure, 'La ganancia no puede ser 0.', 'Valor de ganancia invalido');
                return;
            elseif ganancia_val < 0
                uialert(app.UIFigure, 'La ganancia para este diseño no puede ser negativa', 'Valor de ganancia invalido');
                return;
            end

            app.b_coeff = app.b_coeff * ganancia_val;

            [h, f_hz] = freqz(app.b_coeff, app.a_coeff, 1024, app.Fs);
            w_rad = (f_hz / f_nyquist) * pi;

            %Aca empieza el magnitud

            if app.dBButton.Value == true
                magnitud = 20 * log10(abs(h));
                ylabel_text = 'Magnitud (dB)';
            else
                magnitud = abs(h);
                ylabel_text = 'Magnitud (Lineal)';
            end

            if app.HzButton_2.Value == true
                plot(app.UIAxes2_2, f_hz, magnitud, 'LineWidth', 1.5);
                xlabel(app.UIAxes2_2, 'Frecuencia (Hz)');
            else
                plot(app.UIAxes2_2, w_rad, magnitud, 'LineWidth', 1.5);
                xlabel(app.UIAxes2_2, 'Frecuencia (rad/muestra)');
            end

            title(app.UIAxes2_2, 'Respuesta en Magnitud');
            ylabel(app.UIAxes2_2, ylabel_text);
            grid(app.UIAxes2_2, 'on');

            %Aca empieza lo mismo para la fase
            
            fase = unwrap(angle(h));
            
            if app.HzButton.Value == true
                plot(app.UIAxes_2, f_hz, fase, 'LineWidth', 1.5);
                xlabel(app.UIAxes_2, 'Frecuencia (Hz)');
            else
                plot(app.UIAxes_2, w_rad, fase, 'LineWidth', 1.5);
                xlabel(app.UIAxes_2, 'Frecuencia (rad/muestra)');
            end


            title(app.UIAxes_2, 'Respuesta de fase');
            ylabel(app.UIAxes_2, 'Fase (Radianes)');
            grid(app.UIAxes_2, 'on');

            app.signal_procesada = filtfilt(app.b_coeff, app.a_coeff, app.signal_original);
            L_procesada = length(app.signal_procesada);
            L_original = length(app.signal_original);
            t_salida = (0:L_procesada-1) / app.Fs;
            t_entrada = (0:L_original-1) / app.Fs;

            %Graficando senal original para comparacion en el apartado de
            %salida
            plot(app.UIAxes_4, t_entrada, app.signal_original, 'r');
            title(app.UIAxes_4, 'Señal de Entrada Original')
            xlabel(app.UIAxes_4, 'Tiempo (s)');
            ylabel(app.UIAxes_4, 'Amplitud');
            grid(app.UIAxes_4, 'on');

            %Graficando senal procesada en el apartado de salida
            plot(app.UIAxes_5, t_salida, app.signal_procesada, 'b');
            title(app.UIAxes_5, 'Señal de Salida con el filtro aplicado');
            xlabel(app.UIAxes_5, 'Tiempo (s)');
            ylabel(app.UIAxes_5, 'Amplitud');
            grid(app.UIAxes_5, 'on');
                

            signal_sin_dc = app.signal_original - mean(app.signal_original);
            signal_sin_dc_procesada = app.signal_procesada - mean(app.signal_procesada);
            
            Y = fft(signal_sin_dc);
            Y_procesada = fft(signal_sin_dc_procesada);
            
            espectro = abs(Y/L_original);
            espectro_procesado = abs(Y_procesada/L_procesada);

            espectro_uni = espectro(1 : floor(L_original/2) + 1);
            espectro_uni_procesado = espectro_procesado(1 : floor(L_procesada/2) + 1);


            espectro_uni(2:end-1) = 2 * espectro_uni(2:end-1);
            espectro_uni_procesado(2:end-1) = 2 * espectro_uni_procesado(2:end-1);

            
            frecuencia_original = app.Fs*(0 : (L_original/2)) / L_original;
            frecuencia_procesada = app.Fs*(0 : (L_procesada/2)) / L_procesada;

            plot(app.UIAxes2_4, frecuencia_original, espectro_uni, 'r');
            title(app.UIAxes2_4, 'Señal de entrada en dominio de la frecuencia');
            xlabel(app.UIAxes2_4, 'Frecuencia (Hz)');
            ylabel(app.UIAxes2_4, 'Amplitud');

            plot(app.UIAxes2_5, frecuencia_procesada, espectro_uni_procesado, 'b');
            title(app.UIAxes2_5, 'Señal de salida en dominio de la frecuencia');
            xlabel(app.UIAxes2_5, 'Frecuencia (Hz)');
            ylabel(app.UIAxes2_5, 'Amplitud');


        end

        % Value changed function: FiltroDropDown
        function FiltroDropDownValueChanged(app, event)
    
        end

        % Value changed function: CategoriaDropDown
        function CategoriaDropDownValueChanged(app, event)
            categoria_filtro = app.CategoriaDropDown.Value;

        if strcmp(categoria_filtro, 'Low-pass') || strcmp(categoria_filtro, 'High-pass')
            app.Fcorte1EditField.Enable = 'off';
            app.Fcorte2EditField.Enable = 'off';
            app.FcEditField.Enable = 'on';
        else
            app.Fcorte1EditField.Enable = 'on';
            app.Fcorte2EditField.Enable = 'on';
            app.FcEditField.Enable = 'off';
        end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntradaTab = uitab(app.TabGroup);
            app.EntradaTab.Title = 'Entrada';
            app.UIAxes = uiaxes(app.EntradaTab);
            title(app.UIAxes, 'Señal Original')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.EntradaTab);
            title(app.UIAxes2, 'Señal en dominio de frecuencia')
            xlabel(app.UIAxes2, 'Frecuencia (z)')
            ylabel(app.UIAxes2, 'Amplitud')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.EntradaTab);
            title(app.UIAxes3, 'Histograma de Amplitud (Ruido)')
            xlabel(app.UIAxes3, 'Amplitud')
            ylabel(app.UIAxes3, 'Conteo')
            zlabel(app.UIAxes3, 'Z')
            app.EditField_3 = uieditfield(app.EntradaTab, 'text');
            app.EditField_6 = uieditfield(app.EntradaTab, 'text');
            app.EditField_2 = uieditfield(app.EntradaTab, 'text');
            app.EditField = uieditfield(app.EntradaTab, 'text');
            app.SeleccionarelCOMDropDownLabel = uilabel(app.EntradaTab);
            app.SeleccionarelCOMDropDownLabel.Text = 'Seleccionar el COM';
            app.SeleccionarelCOMDropDown = uidropdown(app.EntradaTab);
            app.SeleccionarelCOMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12'};
            app.SeleccionarelCOMDropDown.Value = 'COM1';
            app.ConectarButton = uibutton(app.EntradaTab, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.LimpiarButton = uibutton(app.EntradaTab, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.DetenerButton = uibutton(app.EntradaTab, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.SourceButtonGroup = uibuttongroup(app.EntradaTab);
            app.SourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceButtonGroupSelectionChanged, true);
            app.SourceButtonGroup.Title = 'Source';
            app.OnlineButton = uiradiobutton(app.SourceButtonGroup);
            app.OnlineButton.Text = 'Online';
            app.OnlineButton.Value = true;
            app.OfflineButton = uiradiobutton(app.SourceButtonGroup);
            app.OfflineButton.Text = 'Offline';
            app.BaudrateDropDownLabel = uilabel(app.EntradaTab);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.EntradaTab);
            app.BaudrateDropDown.Items = {'9600', '115200'};
            app.BaudrateDropDown.Value = '9600';
            app.OnlineLabel = uilabel(app.EntradaTab);
            app.OnlineLabel.Text = 'Online';
            app.OfflineLabel = uilabel(app.EntradaTab);
            app.OfflineLabel.Text = 'Offline';
            app.CapturarButton = uibutton(app.EntradaTab, 'push');
            app.CapturarButton.Text = 'Capturar';
            app.CargarmatButton = uibutton(app.EntradaTab, 'push');
            app.CargarmatButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmatButtonPushed, true);
            app.CargarmatButton.Text = 'Cargar .mat';
            app.FsEditFieldLabel = uilabel(app.EntradaTab);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.EntradaTab, 'numeric');
            app.FsEditField.ValueChangedFcn = createCallbackFcn(app, @FsEditFieldValueChanged, true);
            app.MuestrasEditFieldLabel = uilabel(app.EntradaTab);
            app.MuestrasEditFieldLabel.Text = 'Muestras';
            app.MuestrasEditField = uieditfield(app.EntradaTab, 'numeric');
            app.TiemposEditFieldLabel = uilabel(app.EntradaTab);
            app.TiemposEditFieldLabel.Text = 'Tiempo (s)';
            app.TiemposEditField = uieditfield(app.EntradaTab, 'numeric');
            app.MuestrasobtenidasEditFieldLabel = uilabel(app.EntradaTab);
            app.MuestrasobtenidasEditFieldLabel.Text = 'Muestras obtenidas';
            app.MuestrasobtenidasEditField = uieditfield(app.EntradaTab, 'numeric');
            app.DiseoOfflineTab = uitab(app.TabGroup);
            app.DiseoOfflineTab.Title = 'Diseño Offline';
            app.UIAxes_2 = uiaxes(app.DiseoOfflineTab);
            title(app.UIAxes_2, 'Fase')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.DiseoOfflineTab);
            title(app.UIAxes2_2, 'Magnitud')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.EditField_4 = uieditfield(app.DiseoOfflineTab, 'text');
            app.TipodeFiltroButtonGroup = uibuttongroup(app.DiseoOfflineTab);
            app.TipodeFiltroButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodefiltroButtonGroup_2SelectionChanged, true);
            app.TipodeFiltroButtonGroup.Title = 'Tipo de Filtro';
            app.IIRButton = uiradiobutton(app.TipodeFiltroButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.IIRButton.Value = true;
            app.FIRButton = uiradiobutton(app.TipodeFiltroButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.FiltroDropDownLabel = uilabel(app.DiseoOfflineTab);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.DiseoOfflineTab);
            app.FiltroDropDown.Items = {'Butterworth', 'Cheby1', 'Cheby2', 'Bessel', 'Eliptico'};
            app.FiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDownValueChanged, true);
            app.FiltroDropDown.Value = 'Butterworth';
            app.EditField_5 = uieditfield(app.DiseoOfflineTab, 'text');
            app.EfectosDropDownLabel = uilabel(app.DiseoOfflineTab);
            app.EfectosDropDownLabel.Text = 'Efectos';
            app.EfectosDropDown = uidropdown(app.DiseoOfflineTab);
            app.EfectosDropDown.Items = {'Ninguno', 'Eco', 'Distorsion', 'Pitch alto', 'Pitch bajo'};
            app.EfectosDropDown.Value = 'Ninguno';
            app.FcEditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.GananciaEditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.GananciaEditFieldLabel.Text = 'Ganancia';
            app.GananciaEditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.GananciaEditField.Value = 1;
            app.RetrasoEditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.RetrasoEditFieldLabel.Text = 'Retraso';
            app.RetrasoEditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.OrdenEditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.OrdenEditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.Fcorte1EditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.Fcorte1EditFieldLabel.Text = 'F corte 1';
            app.Fcorte1EditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.Fcorte2EditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.Fcorte2EditFieldLabel.Text = 'F corte 2';
            app.Fcorte2EditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.AplicarButton = uibutton(app.DiseoOfflineTab, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButton_2Pushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.AlternarejeHorizontalFaseButtonGroup = uibuttongroup(app.DiseoOfflineTab);
            app.AlternarejeHorizontalFaseButtonGroup.Title = 'Alternar eje Horizontal (Fase)';
            app.HzButton = uiradiobutton(app.AlternarejeHorizontalFaseButtonGroup);
            app.HzButton.Text = 'Hz';
            app.HzButton.Value = true;
            app.RadMuestraButton = uiradiobutton(app.AlternarejeHorizontalFaseButtonGroup);
            app.RadMuestraButton.Text = 'Rad/Muestra';
            app.AlternarejeHorizontalMagnitudButtonGroup = uibuttongroup(app.DiseoOfflineTab);
            app.AlternarejeHorizontalMagnitudButtonGroup.Title = 'Alternar eje Horizontal (Magnitud)';
            app.HzButton_2 = uiradiobutton(app.AlternarejeHorizontalMagnitudButtonGroup);
            app.HzButton_2.Text = 'Hz';
            app.HzButton_2.Value = true;
            app.RadMuestraButton_2 = uiradiobutton(app.AlternarejeHorizontalMagnitudButtonGroup);
            app.RadMuestraButton_2.Text = 'Rad/Muestra';
            app.AlternarejeVerticalMagnitudButtonGroup = uibuttongroup(app.DiseoOfflineTab);
            app.AlternarejeVerticalMagnitudButtonGroup.Title = 'Alternar eje Vertical (Magnitud)';
            app.dBButton = uiradiobutton(app.AlternarejeVerticalMagnitudButtonGroup);
            app.dBButton.Text = 'dB';
            app.dBButton.Value = true;
            app.LinealButton = uiradiobutton(app.AlternarejeVerticalMagnitudButtonGroup);
            app.LinealButton.Text = 'Lineal';
            app.EditField_9 = uieditfield(app.DiseoOfflineTab, 'text');
            app.CategoriaDropDownLabel = uilabel(app.DiseoOfflineTab);
            app.CategoriaDropDownLabel.Text = 'Categoria';
            app.CategoriaDropDown = uidropdown(app.DiseoOfflineTab);
            app.CategoriaDropDown.Items = {'Low-pass', 'Band-pass', 'High-pass', 'Band-filter'};
            app.CategoriaDropDown.ValueChangedFcn = createCallbackFcn(app, @CategoriaDropDownValueChanged, true);
            app.CategoriaDropDown.Value = 'Low-pass';
            app.DiseoOnlineTab = uitab(app.TabGroup);
            app.DiseoOnlineTab.Title = 'Diseño Online';
            app.UIAxes_3 = uiaxes(app.DiseoOnlineTab);
            title(app.UIAxes_3, 'Fase')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2_3 = uiaxes(app.DiseoOnlineTab);
            title(app.UIAxes2_3, 'Magnitud')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.EditField_7 = uieditfield(app.DiseoOnlineTab, 'text');
            app.FiltroDropDown_2Label = uilabel(app.DiseoOnlineTab);
            app.FiltroDropDown_2Label.Text = 'Filtro';
            app.FiltroDropDown_2 = uidropdown(app.DiseoOnlineTab);
            app.FiltroDropDown_2.Items = {'Butterworth', 'Chevy 1', 'Chevy 2', 'Bessel', 'Eliptico'};
            app.FiltroDropDown_2.Value = 'Butterworth';
            app.EditField_8 = uieditfield(app.DiseoOnlineTab, 'text');
            app.EfectosDropDown_2Label = uilabel(app.DiseoOnlineTab);
            app.EfectosDropDown_2Label.Text = 'Efectos';
            app.EfectosDropDown_2 = uidropdown(app.DiseoOnlineTab);
            app.EfectosDropDown_2.Items = {'Eco', 'Distorsion', 'Pitch alto', 'Pitch bajo'};
            app.EfectosDropDown_2.Value = 'Eco';
            app.AlternarejeHorizontalFaseButtonGroup_2 = uibuttongroup(app.DiseoOnlineTab);
            app.AlternarejeHorizontalFaseButtonGroup_2.Title = 'Alternar eje Horizontal (Fase)';
            app.HzButton_3 = uiradiobutton(app.AlternarejeHorizontalFaseButtonGroup_2);
            app.HzButton_3.Text = 'Hz';
            app.HzButton_3.Value = true;
            app.RadMuestraButton_3 = uiradiobutton(app.AlternarejeHorizontalFaseButtonGroup_2);
            app.RadMuestraButton_3.Text = 'Rad/Muestra';
            app.AlternarejeHorizontalMagnitudButtonGroup_2 = uibuttongroup(app.DiseoOnlineTab);
            app.AlternarejeHorizontalMagnitudButtonGroup_2.Title = 'Alternar eje Horizontal (Magnitud)';
            app.HzButton_4 = uiradiobutton(app.AlternarejeHorizontalMagnitudButtonGroup_2);
            app.HzButton_4.Text = 'Hz';
            app.HzButton_4.Value = true;
            app.RadMuestraButton_4 = uiradiobutton(app.AlternarejeHorizontalMagnitudButtonGroup_2);
            app.RadMuestraButton_4.Text = 'Rad/Muestra';
            app.AlternarejeVerticalMagnitudButtonGroup_2 = uibuttongroup(app.DiseoOnlineTab);
            app.AlternarejeVerticalMagnitudButtonGroup_2.Title = 'Alternar eje Vertical (Magnitud)';
            app.dBButton_2 = uiradiobutton(app.AlternarejeVerticalMagnitudButtonGroup_2);
            app.dBButton_2.Text = 'dB';
            app.dBButton_2.Value = true;
            app.LinealButton_2 = uiradiobutton(app.AlternarejeVerticalMagnitudButtonGroup_2);
            app.LinealButton_2.Text = 'Lineal';
            app.AplicarButton_2 = uibutton(app.DiseoOnlineTab, 'push');
            app.AplicarButton_2.Text = 'Aplicar';
            app.Fcorte2EditField_2Label = uilabel(app.DiseoOnlineTab);
            app.Fcorte2EditField_2Label.Text = 'F corte 2';
            app.Fcorte2EditField_2 = uieditfield(app.DiseoOnlineTab, 'numeric');
            app.Fcorte1EditField_2Label = uilabel(app.DiseoOnlineTab);
            app.Fcorte1EditField_2Label.Text = 'F corte 1';
            app.Fcorte1EditField_2 = uieditfield(app.DiseoOnlineTab, 'numeric');
            app.OrdenEditField_2Label = uilabel(app.DiseoOnlineTab);
            app.OrdenEditField_2Label.Text = 'Orden';
            app.OrdenEditField_2 = uieditfield(app.DiseoOnlineTab, 'numeric');
            app.RetrasoEditField_2Label = uilabel(app.DiseoOnlineTab);
            app.RetrasoEditField_2Label.Text = 'Retraso';
            app.RetrasoEditField_2 = uieditfield(app.DiseoOnlineTab, 'numeric');
            app.GananciaEditField_2Label = uilabel(app.DiseoOnlineTab);
            app.GananciaEditField_2Label.Text = 'Ganancia';
            app.GananciaEditField_2 = uieditfield(app.DiseoOnlineTab, 'numeric');
            app.FcEditField_2Label = uilabel(app.DiseoOnlineTab);
            app.FcEditField_2Label.Text = 'Fc';
            app.FcEditField_2 = uieditfield(app.DiseoOnlineTab, 'numeric');
            app.SalidaTab = uitab(app.TabGroup);
            app.SalidaTab.Title = 'Salida';
            app.UIAxes_4 = uiaxes(app.SalidaTab);
            title(app.UIAxes_4, 'Señal de Entrada')
            xlabel(app.UIAxes_4, 'Tiempo (s)')
            ylabel(app.UIAxes_4, 'Amplitud')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.SalidaTab);
            title(app.UIAxes_5, 'Señal de Salida')
            xlabel(app.UIAxes_5, 'Tiempo (s)')
            ylabel(app.UIAxes_5, 'Amplitud')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes2_4 = uiaxes(app.SalidaTab);
            title(app.UIAxes2_4, 'Señal de entrada en dominio de frecuencia')
            xlabel(app.UIAxes2_4, 'Frecuencia (z)')
            ylabel(app.UIAxes2_4, 'Amplitud')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_5 = uiaxes(app.SalidaTab);
            title(app.UIAxes2_5, 'Señal de salida en dominio de frecuencia')
            xlabel(app.UIAxes2_5, 'Frecuencia (z)')
            ylabel(app.UIAxes2_5, 'Amplitud')
            zlabel(app.UIAxes2_5, 'Z')
            app.ExportarencsvButton = uibutton(app.SalidaTab, 'push');
            app.ExportarencsvButton.Text = 'Exportar en .csv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end